classdef pantallaInicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TestNetworkButton   matlab.ui.control.Button
        Image               matlab.ui.control.Image
        BienvenidoEstaaplicacintepermitLabel  matlab.ui.control.Label
        TrainNetworkButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        pantallaTrain % Description
        pantallaTest % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            app.pantallaTrain = pantallaTrain();
            app.pantallaTrain.UIFigure.Visible = 'on';         
        end

        % Button pushed function: TestNetworkButton
        function TestNetworkButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            app.pantallaTest = pantallaTest();
            app.pantallaTest.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.BienvenidoEstaaplicacintepermitLabel = uilabel(app.UIFigure);
            app.BienvenidoEstaaplicacintepermitLabel.Text = {'Bienvenido! Esta aplicación permite entrenar un conjunto de '; '           movimientos así como clasificarlos. Disfruta!'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'C:\Users\rodri\MATLAB Drive\TFG Infor\Imágenes\SevillanasConCopyright.JPG';
            app.TestNetworkButton = uibutton(app.UIFigure, 'push');
            app.TestNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TestNetworkButtonPushed, true);
            app.TestNetworkButton.Text = 'Test Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pantallaInicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end