classdef pantallaParametros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RazonAprendKnob                 matlab.ui.control.DiscreteKnob
        RazndeaprendizajeKnobLabel_2    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        NumMinilotesGauge               matlab.ui.control.SemicircularGauge
        NumEpocasGauge                  matlab.ui.control.SemicircularGauge
        NumMinilotesEditField           matlab.ui.control.NumericEditField
        Nmerodeminilotes111EditFieldLabel  matlab.ui.control.Label
        NumEpocasEditField              matlab.ui.control.NumericEditField
        Nmerodepocas1100EditFieldLabel  matlab.ui.control.Label
        ComenzarEntrenSwitch            matlab.ui.control.Switch
        TodoajustadoPuesvamosaempezarLabel  matlab.ui.control.Label
        NotaLabel                       matlab.ui.control.Label
        UmbralGradienteKnob             matlab.ui.control.DiscreteKnob
        UmbraldelgradienteLabel         matlab.ui.control.Label
        OptimizadorButtonGroup          matlab.ui.container.ButtonGroup
        adamButton                      matlab.ui.control.RadioButton
        sgdmButton                      matlab.ui.control.RadioButton
        PrimerPasoLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        Datos %Public para poder pasarlos desde la otra pantalla
    end
    
    properties (Access = private)
        layers % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            numFeatures = 3;
            numHiddenUnits = 200;
            numClasses = 5;

            app.layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(numHiddenUnits,'OutputMode','sequence')
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
        end

        % Value changed function: ComenzarEntrenSwitch
        function ComenzarEntrenSwitchValueChanged(app, event)
            value = app.ComenzarEntrenSwitch.Value;
            %cuando empezamos el entrenamiento, bloqueamos tdos los botones
            %----PONER BOTON PARAR ENTRENAMIENTO QUE LOS ACTIVE OTRA VEZ
            app.ComenzarEntrenSwitch.Enable = "off";
            app.OptimizadorButtonGroup.Enable = "off";
            app.UmbralGradienteKnob.Enable = "off";
            app.RazonAprendKnob.Enable = "off";
            app.NumEpocasEditField.Enable = "off";
            app.NumMinilotesEditField.Enable = "off";

            %comenzar entrenamiento
            %NOTA: LOS GAUGE NO SE MODIFICAN CON EL CLICK SINO QUE HAY QUE
            %JUNTARLOS CON UN TEXTLABEL Y REPRESENTAN EL VALOR QUE HAYA.
            optimizador = app.OptimizadorButtonGroup.SelectedObject.Text; %dev el string(nombre) del boton seleccionado
            epocas = app.NumEpocasEditField.Value;           %dev un int
            umbralGrad = str2double(app.UmbralGradienteKnob.Value);   %value dev. string y lo pasamos a double
            razonAp = str2double(app.RazonAprendKnob.Value);   %value dev. string y lo pasamos a double
            
            %ESTA HAY QUE CAMBIARLA AL TAMAÑO DEL MINILOTE  
            minilotes = app.NumMinilotesEditField.Value;         %dev un int

            options = trainingOptions(optimizador, ...
                'MaxEpochs',epocas, ...
                'GradientThreshold',umbralGrad, ...
                'Verbose',0, ...
                'Plots','training-progress',...
                'InitialLearnRate', razonAp);
            
            %ENTRENAMOS
            Xt = app.Datos.XTrain;
            Yt = app.Datos.YTrain;
            capas = app.layers;
            net = trainNetwork(Xt, Yt, capas, options);
           
        end

        % Value changed function: NumEpocasEditField
        function NumEpocasEditFieldValueChanged(app, event)
            value = app.NumEpocasEditField.Value;
            if value > 0 && value < 101
                app.NumEpocasGauge.Value = value;
            else
                app.NumEpocasGauge.Value = 60;
                app.NumEpocasEditField.Value = 60;
            end
        end

        % Value changed function: NumMinilotesEditField
        function NumMinilotesEditFieldValueChanged(app, event)
            value = app.NumMinilotesEditField.Value;
            if value > 0 && value < 12
                app.NumMinilotesGauge.Value = value;
            else
                app.NumMinilotessGauge.Value = 1;
                app.NumMinilotesEditField.Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrimerPasoLabel = uilabel(app.UIFigure);
            app.PrimerPasoLabel.Text = ' Ha llegado el momento de elegir los parámetros de entrenamiento de nuestra red LSTM';
            app.OptimizadorButtonGroup = uibuttongroup(app.UIFigure);
            app.OptimizadorButtonGroup.Title = '        Optimizador';
            app.sgdmButton = uiradiobutton(app.OptimizadorButtonGroup);
            app.sgdmButton.Text = 'sgdm';
            app.sgdmButton.Value = true;
            app.adamButton = uiradiobutton(app.OptimizadorButtonGroup);
            app.adamButton.Text = 'adam';
            app.UmbraldelgradienteLabel = uilabel(app.UIFigure);
            app.UmbraldelgradienteLabel.Text = 'Umbral del gradiente';
            app.UmbralGradienteKnob = uiknob(app.UIFigure, 'discrete');
            app.UmbralGradienteKnob.Items = {'0.5', '1', '2', '3', '5'};
            app.UmbralGradienteKnob.Value = '2';
            app.NotaLabel = uilabel(app.UIFigure);
            app.NotaLabel.Text = {'    NOTA: Si se aumenta el número de minilotes (ej. 3), las actualizaciones/época'; ' se incrementan proporcionalmente (ej. x3) y con ello, el tiempo de entrenamiento.'};
            app.TodoajustadoPuesvamosaempezarLabel = uilabel(app.UIFigure);
            app.TodoajustadoPuesvamosaempezarLabel.Text = '¿Todo ajustado? Pues vamos a empezar';
            app.ComenzarEntrenSwitch = uiswitch(app.UIFigure, 'slider');
            app.ComenzarEntrenSwitch.ValueChangedFcn = createCallbackFcn(app, @ComenzarEntrenSwitchValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Nmerodepocas1100EditFieldLabel = uilabel(app.Panel);
            app.Nmerodepocas1100EditFieldLabel.Text = 'Número de épocas (1-100): ';
            app.NumEpocasEditField = uieditfield(app.Panel, 'numeric');
            app.NumEpocasEditField.Limits = [1 100];
            app.NumEpocasEditField.ValueChangedFcn = createCallbackFcn(app, @NumEpocasEditFieldValueChanged, true);
            app.NumEpocasEditField.Value = 60;
            app.Nmerodeminilotes111EditFieldLabel = uilabel(app.Panel);
            app.Nmerodeminilotes111EditFieldLabel.Text = 'Número de minilotes (1-11): ';
            app.NumMinilotesEditField = uieditfield(app.Panel, 'numeric');
            app.NumMinilotesEditField.Limits = [1 11];
            app.NumMinilotesEditField.ValueChangedFcn = createCallbackFcn(app, @NumMinilotesEditFieldValueChanged, true);
            app.NumMinilotesEditField.Value = 1;
            app.NumEpocasGauge = uigauge(app.Panel, 'semicircular');
            app.NumEpocasGauge.Limits = [1 100];
            app.NumEpocasGauge.MajorTicks = [1 20 40 60 80 100];
            app.NumEpocasGauge.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.NumEpocasGauge.Value = 60;
            app.NumMinilotesGauge = uigauge(app.Panel, 'semicircular');
            app.NumMinilotesGauge.Limits = [1 11];
            app.NumMinilotesGauge.MajorTicks = [1 3 5 7 9 11];
            app.NumMinilotesGauge.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumMinilotesGauge.Value = 1;
            app.RazndeaprendizajeKnobLabel_2 = uilabel(app.UIFigure);
            app.RazndeaprendizajeKnobLabel_2.Text = 'Razón de aprendizaje';
            app.RazonAprendKnob = uiknob(app.UIFigure, 'discrete');
            app.RazonAprendKnob.Items = {'0.001', '0.005', '0.02', '0.05', '  0.1'};
            app.RazonAprendKnob.Value = '0.001';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pantallaParametros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end