classdef pantallaResultados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        CalcularButton    matlab.ui.control.Button
        PrecLabel         matlab.ui.control.Label
        PrecGauge         matlab.ui.control.LinearGauge
        PRECISINLabel     matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        CompararButton    matlab.ui.control.Button
        Label             matlab.ui.control.Label
        SegundoPasoLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        YT % Description
        YPred
        Acc % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CompararButton
        function CompararButtonPushed(app, event)
            Yp = app.YPred;
            Yt = app.YT;

            Ac = app.Acc;
            Ac
            
            figure
            plot(Yp,'.-')
            hold on
            plot(Yt)
            hold off          
            xlabel("Paso de tiempo")
            ylabel("Movimiento")
            title("Comparación de la predicción y la muestra")
            legend(["Predicho" "Correcto"])
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            Ac = app.Acc;
            app.PrecGauge.Value = Ac; %%dara error si no lo llamamos desde Test
            app.PrecLabel.Text = num2str(Ac);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SegundoPasoLabel = uilabel(app.UIFigure);
            app.SegundoPasoLabel.Text = ' Los datos se han clasificado correctamente. Vamos a comprobar los resultados:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'        La precisión es un buen indicador de la calidad del entrenamiento de la red.'; '   Se calcula dividiendo el número de aciertos entre el número total de clasificaciones.'};
            app.CompararButton = uibutton(app.UIFigure, 'push');
            app.CompararButton.ButtonPushedFcn = createCallbackFcn(app, @CompararButtonPushed, true);
            app.CompararButton.Text = 'VER LA GRÁFICA DE COMPARACIÓN';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'  Visualizar los movimientos predichos en comparación con los movimientos correctos '; '           es la mejor manera de ubicar los fallos y lograr un mayor entendimiento. '};
            app.PRECISINLabel = uilabel(app.UIFigure);
            app.PRECISINLabel.Text = 'PRECISIÓN:';
            app.PrecGauge = uigauge(app.UIFigure, 'linear');
            app.PrecGauge.Limits = [0.99 1];
            app.PrecLabel = uilabel(app.UIFigure);
            app.PrecLabel.Text = '';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pantallaResultados

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end