classdef pantallaTrain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ListoButton                  matlab.ui.control.StateButton
        TercerPasoLabel              matlab.ui.control.Label
        SegundPasoLabel              matlab.ui.control.Label
        PrimerPasoLabel              matlab.ui.control.Label
        CargarPanel                  matlab.ui.container.Panel
        NumBloqueEditField           matlab.ui.control.NumericEditField
        NmerodebloqueEditFieldLabel  matlab.ui.control.Label
        CaracteristicaDropDown       matlab.ui.control.DropDown
        EligelacaractersticaarepresentarDropDownLabel  matlab.ui.control.Label
        NumBloquesLabel              matlab.ui.control.Label
        GraficaButton                matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ProcesoCargaLamp             matlab.ui.control.Lamp
        CargarLabel                  matlab.ui.control.Label
        CargarButton                 matlab.ui.control.Button
        ElegirDatosPanel             matlab.ui.container.Panel
        CargarPredetListBox          matlab.ui.control.ListBox
        SeleccionardatospredeterminadosLabel  matlab.ui.control.Label
        NombredelarchivoLabel        matlab.ui.control.Label
        LabelRuta                    matlab.ui.control.Label
        ExplorarButton               matlab.ui.control.Button
    end

    
    properties (Access = private)  %variables globales
        Datos % Description
        pantallaParametros % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            %value = app.CargarButton.Value;
            nombreArchivo = app.LabelRuta.Text;
            if isequal(nombreArchivo, 'No se ha seleccionado ningún archivo.') %si hay archivo
                 app.ProcesoCargaLamp.Color = 'red';
            else
                app.ProcesoCargaLamp.Color = 'yellow';
                try
                    app.Datos = load(nombreArchivo, 'XTrain', 'YTrain'); %VARIABLE GLOBAL
                    %size(app.Datos.XTrain,1) %muestra num de filas Xtrain
                    app.ProcesoCargaLamp.Color = 'green';
                    app.CargarLabel.Text = ['Carga completada, dimensiones ', num2str(size(app.Datos.XTrain,1)), 'x', num2str(size(app.Datos.XTrain,2))];
                    app.GraficaButton.Enable = "on";
                    app.NumBloqueEditField.Enable = "on";
                    app.CaracteristicaDropDown.Enable = "on";
                    app.ListoButton.Visible = "on";
                    app.TercerPasoLabel.Visible = "on";
                catch ME
                    % Capturar el error y mostrar un mensaje adecuado
                    if strcmp(ME.identifier, 'MATLAB:load:couldNotReadFile')
                        app.CargarLabel.Text = ['No se encontró el archivo ', nombreArchivo];
                    else
                        app.CargarLabel.Text = ['Error al cargar el archivo ', nombreArchivo];
                    end
                    app.ProcesoCargaLamp.Color = 'red';
                    app.GraficaButton.Enable = "off";
                    app.NumBloqueEditField.Enable = "off";
                    app.CaracteristicaDropDown.Enable = "off";
                    app.ListoButton.Visible = "off";
                    app.TercerPasoLabel.Visible = "off";
                end    
            end
       
        end

        % Button pushed function: ExplorarButton
        function ExplorarButtonPushed(app, event)
             %[filename, filepath] = uigetfile(filter, dialogTitle);
            %filter es para el tipo de archivo como '*.txt'
            [filename, ~] = uigetfile('*.*', 'Selecciona un archivo'); 

            % Comprueba si el usuario ha seleccionado un archivo
            if isequal(filename, 0) %compara 2 vars. A y B
                app.LabelRuta.Text = 'No se ha seleccionado ningún archivo.';
                app.CargarPredetListBox.Value = '-';
            else
                %app.NosehaseleccionadoningnarchivoLabel.Text = ['Archivo seleccionado: ', fullfile(filepath, filename)];
                app.LabelRuta.Text = filename;
                app.CargarPredetListBox.Value = '-';
                %load filename;
            end
        end

        % Value changed function: CargarPredetListBox
        function CargarPredetListBoxValueChanged(app, event)
            value = app.CargarPredetListBox.Value;
            if isequal(value, '-') %si se elige '-'
                app.LabelRuta.Text = 'No se ha seleccionado ningún archivo.';
            else
                app.LabelRuta.Text = value;
            end
        end

        % Button pushed function: GraficaButton
        function GraficaButtonPushed(app, event)
            %inicializar XTRAIN e YTRAIN
            coord = app.CaracteristicaDropDown.ValueIndex;
            coordLetra = 'x';
            switch coord
                case 2
                    coordLetra = 'y';
                case 3
                    coordLetra = 'z';
            end

            numBloque = app.NumBloqueEditField.Value;
            
            X = app.Datos.XTrain{numBloque}(coord,:);
            classes = categories(app.Datos.YTrain{numBloque});

            figure(1)
            for j = 1:numel(classes)
                label = classes(j);
                idx = find(app.Datos.YTrain{numBloque} == label);
                hold on
                plot(idx,X(idx))
            end
            hold off

            xlabel("Paso de Tiempo")
            ylabel("Aceleración")
            title(['Secuencia de entrenamiento ' num2str(numBloque) ', atributo ' num2str(coord) ' (' coordLetra ')'])
            legend(classes,'Location','northwest')
        end

        % Value changed function: ListoButton
        function ListoButtonValueChanged(app, event)
          
            app.UIFigure.Visible = 'off';
            app.pantallaParametros = pantallaParametros();
            app.pantallaParametros.Datos = app.Datos;
            app.pantallaParametros.UIFigure.Visible = 'on';
        end

        % Value changed function: NumBloqueEditField
        function NumBloqueEditFieldValueChanged(app, event)
            value = app.NumBloqueEditField.Value;
            numBloques = size(app.Datos.XTrain,1);
            if value > numBloques
                app.NumBloquesLabel.Text = ['Introduce un número entre 1 y ', num2str(numBloques)];
                app.NumBloqueEditField.Value = 1;
            else
                app.NumBloquesLabel.Text = ['Valor correcto'];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ElegirDatosPanel = uipanel(app.UIFigure);
            app.ExplorarButton = uibutton(app.ElegirDatosPanel, 'push');
            app.ExplorarButton.ButtonPushedFcn = createCallbackFcn(app, @ExplorarButtonPushed, true);
            app.ExplorarButton.Text = 'Selecciona tus archivos .mat del explorador';
            app.LabelRuta = uilabel(app.ElegirDatosPanel);
            app.LabelRuta.Text = 'No se ha seleccionado ningún archivo.';
            app.NombredelarchivoLabel = uilabel(app.ElegirDatosPanel);
            app.NombredelarchivoLabel.Text = 'Nombre del archivo:';
            app.SeleccionardatospredeterminadosLabel = uilabel(app.ElegirDatosPanel);
            app.SeleccionardatospredeterminadosLabel.Text = {'Seleccionar archivos'; ' predeterminados:'};
            app.CargarPredetListBox = uilistbox(app.ElegirDatosPanel);
            app.CargarPredetListBox.Items = {'-', 'HumanActivityTrain', 'MovementDataset', 'DBHumanMoves'};
            app.CargarPredetListBox.ValueChangedFcn = createCallbackFcn(app, @CargarPredetListBoxValueChanged, true);
            app.CargarPredetListBox.Value = '-';
            app.CargarPanel = uipanel(app.UIFigure);
            app.CargarButton = uibutton(app.CargarPanel, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar los datos de entrenamiento elegidos:';
            app.CargarLabel = uilabel(app.CargarPanel);
            app.CargarLabel.Text = 'Aún no ha comenzado la descarga';
            app.ProcesoCargaLamp = uilamp(app.CargarPanel);
            app.Label = uilabel(app.CargarPanel);
            app.Label.Text = {''; ''};
            app.GraficaButton = uibutton(app.CargarPanel, 'push');
            app.GraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaButtonPushed, true);
            app.GraficaButton.Enable = 'off';
            app.GraficaButton.Text = {'Pulsa para visualizar una secuencia'; ' de entrenamiento en una gráfica'};
            app.NumBloquesLabel = uilabel(app.CargarPanel);
            app.NumBloquesLabel.Text = '';
            app.EligelacaractersticaarepresentarDropDownLabel = uilabel(app.CargarPanel);
            app.EligelacaractersticaarepresentarDropDownLabel.Enable = 'off';
            app.EligelacaractersticaarepresentarDropDownLabel.Text = {'Elige la característica'; ' a representar:'};
            app.CaracteristicaDropDown = uidropdown(app.CargarPanel);
            app.CaracteristicaDropDown.Items = {'x', 'y', 'z'};
            app.CaracteristicaDropDown.Enable = 'off';
            app.CaracteristicaDropDown.Value = 'x';
            app.NmerodebloqueEditFieldLabel = uilabel(app.CargarPanel);
            app.NmerodebloqueEditFieldLabel.Enable = 'off';
            app.NmerodebloqueEditFieldLabel.Text = 'Número de bloque:';
            app.NumBloqueEditField = uieditfield(app.CargarPanel, 'numeric');
            app.NumBloqueEditField.Limits = [1 Inf];
            app.NumBloqueEditField.ValueChangedFcn = createCallbackFcn(app, @NumBloqueEditFieldValueChanged, true);
            app.NumBloqueEditField.Enable = 'off';
            app.NumBloqueEditField.Value = 1;
            app.PrimerPasoLabel = uilabel(app.UIFigure);
            app.PrimerPasoLabel.Text = ' Primero selecciona un archivo de datos de entrenamiento. ¡Cuidado con el formato!';
            app.SegundPasoLabel = uilabel(app.UIFigure);
            app.SegundPasoLabel.Text = ' Si estás listo, pulsa el botón inferior para cargar los datos al programa.';
            app.TercerPasoLabel = uilabel(app.UIFigure);
            app.TercerPasoLabel.Visible = 'off';
            app.TercerPasoLabel.Text = '¡Datos listos! Vamos a definir la arquitectura de la red.';
            app.ListoButton = uibutton(app.UIFigure, 'state');
            app.ListoButton.ValueChangedFcn = createCallbackFcn(app, @ListoButtonValueChanged, true);
            app.ListoButton.Visible = 'off';
            app.ListoButton.Text = 'LISTO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pantallaTrain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end